classdef appPIDnew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        speed_2     matlab.ui.control.NumericEditField
        Label_2     matlab.ui.control.Label
        CheckBox    matlab.ui.control.CheckBox
        Button_3    matlab.ui.control.Button
        Lamp        matlab.ui.control.Lamp
        ZButton     matlab.ui.control.StateButton
        YButton     matlab.ui.control.StateButton
        XButton     matlab.ui.control.StateButton
        Zend        matlab.ui.control.NumericEditField
        Yend        matlab.ui.control.NumericEditField
        Xend        matlab.ui.control.NumericEditField
        XLabel      matlab.ui.control.Label
        Zstart      matlab.ui.control.NumericEditField
        Ystart      matlab.ui.control.NumericEditField
        Xstart      matlab.ui.control.NumericEditField
        XLabel_2    matlab.ui.control.Label
        Button_2    matlab.ui.control.Button
        Button      matlab.ui.control.Button
        speed       matlab.ui.control.NumericEditField
        Label       matlab.ui.control.Label
        RightPanel  matlab.ui.container.Panel
        UITable     matlab.ui.control.Table
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %长
            length_cod = 20;
            %宽
            length_kuan= 20;
            % 高
            length_height = 12;
            % 动画帧率
            fps = 60;

            % 是否连续模拟
            contStim = app.CheckBox.Value;
            % 获取输入的位置和速度
            initPos = [0,0,length_height -3];
            startPos = [app.Xstart.Value, app.Ystart.Value, app.Zstart.Value];
            endPos = [app.Xend.Value, app.Yend.Value, app.Zend.Value];
            speedX = double(app.speed.Value)*(2*3.1415*2)/60;
            disp(speedX)
            speedY = double(app.speed_2.Value)*(2*3.1415*2)/60;
            app.Lamp.Color = [1.00,0.00,0.00];
           
            % 设置图形，此处用 app.UIAxes 代替之前的 figure
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            view(app.UIAxes, 3);
            title(app.UIAxes, '运动示意图');
            xlim(app.UIAxes, [-1 length_cod+5]);
            ylim(app.UIAxes, [-1 length_kuan+5]);
            zlim(app.UIAxes, [-1 length_height+2]);
            % 绘制导轨和机械臂
            line(app.UIAxes, [0 0], [0 length_kuan], [length_height length_height], 'LineWidth', 2, 'Color', 'k');
            line(app.UIAxes, [length_cod,length_cod], [0 length_kuan], [length_height length_height], 'LineWidth', 2, 'Color', 'k');
            % 红色的纵杆
            hArmVertical = line(app.UIAxes, [0, 0], [0, 0], [length_height-3, length_height], 'LineWidth', 2, 'Color', 'r');
            % 蓝色的横杆
            hArmHorizontal = line(app.UIAxes, [0 length_cod], [0 0], [length_height, length_height], 'LineWidth', 2, 'Color', 'b');

            % 初始化圆片
            circle_radius = 1;
            theta = linspace(0, 2*pi, 50);
            hCircle = fill3(app.UIAxes, circle_radius * cos(theta) + 0, circle_radius * sin(theta) + 0, zeros(size(theta)) +length_height-3 , 'm', 'LineWidth', 2);

           
            cubeSize = 2;  % 正方体的边长
            [hCube,hSquare,x, y, z,vertices] = setCubicAndPlate(app,startPos, endPos);
            counter = 0;

            % 执行动画
            while true
                app.UITable.Data = [];
                tic;
                animateMovement(app, initPos ,startPos, endPos, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, hCube);
                c = toc;
               
                
                if ~contStim
                    app.Lamp.Color = [0,1,0];
                    break
                else
                    counter = counter+1;
                    % Remove previous shapes if they exist
                    if exist('hSquare', 'var')
                        delete(hSquare);
                    end
                    if exist('hCube', 'var')
                        delete(hCube);
                    end
         
                    initPos = startPos;
                    % 根据计数器的奇偶性选择位置
                    if mod(counter, 2) == 1  % 如果计数器是奇数
                        startPos = [5, 10, 3];
                    else  % 如果计数器是偶数
                        startPos = [18, 10, 3];
                    end
                    endPos(1) = randi([0, length_cod-3]);
                    endPos(2) = randi([0, length_kuan-3]);
                    endPos(3) = 3;
                    [hCube,hSquare,x, y, z,vertices] = setCubicAndPlate(app,startPos, endPos);
                end

            end

           
             function animateMovement(app, initPos ,startPos, endPos,hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, hCube)
                % 同步更新所有元素的位置
                
                tic;
                % 到达目标位置的时间。
                [timeXToObjectPos, timeYToObjectPos, timeZToObjectPos] = calculateMoveToTargetTimes(initPos, endPos, speedX,speedY);
                % 从目标物体到达目标位置所用时间。
                [timeXToBox, timeYToBox, ~] = calculateMoveToTargetTimes(startPos, endPos, speedX,speedY);
                % 从目标位置返回初始化位置所用时间。
                [timeXBack, timeYBack, timeZBack] = calculateMoveToTargetTimes(startPos, initPos, speedX,speedY);

                % 计算刷新帧率 实现动画所用时间就是实际应用时间
                % 理论上我们需要三个这样的时间
                frame_num = max([timeXToObjectPos, timeYToObjectPos]) * fps;
                frame_dt = max([timeXToObjectPos, timeYToObjectPos]) / frame_num;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % 现在我们去实现误差部分 首先让好兄弟移动到错误的位置 这里是初始化误差
                % 定义误差的最大值和基线值
                % 这里最好可以在外面定义好 直接传入初始误差参数
                max_error = 5.0;  % 最大误差，单位可以是米
                base_error = 0.1;  % 基线误差
                initial_error = calculate_error(initPos, endPos, max_error, base_error);
                % 实现pid的部分 我们可以先预定义一些参数
                Kp = 0.38;
                Ki = 0.018;
                Kd = 0.005;
                prev_errorX =  initial_error;
                prev_errorY =  initial_error;

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % 首先到达错误的位置
                % moveToErrorPosition(app, initPos, endPos, initial_error, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, frame_num, frame_dt, length_height, length_cod)
                moveToErrorPosition(app, initPos, endPos, initial_error, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, fps, length_height, length_cod)
                % 暂停0.25s 实现pid控制
                pause(0.25)
                % 更新错误坐标
                errorPosX = endPos(1) + initial_error;
                errorPosY = endPos(2) + initial_error;
                [newX,newY] = adjustPositionWithPID(app,1,endPos, errorPosX, errorPosY, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, hCube,frame_num, frame_dt, length_height, length_cod, Kp, Ki, Kd, prev_errorX, prev_errorY);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                pause(0.25)
                executeZAxisMovement(app, endPos, length_height, hArmVertical, hCircle, hCube, fps, timeZToObjectPos, circle_radius, cubeSize, newX, newY, theta)
                pause(0.25);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % 正方体和圆盘一起移动到放置位置
                [newX, newY] = adjustPositionLinearly(app, startPos,endPos, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, hCube,speedX, speedY,fps, length_height, length_cod);
                
                pause(0.25)
                % 分离动作：圆盘和正方体一起下降
                lowerAndSeparate(hCircle, hArmVertical, hCube, theta, startPos, length_height, cubeSize, frame_num, frame_dt)
                pause(0.25)
                % 仅圆盘上升
                riseOnlyPlate(hCircle, hArmVertical, theta, startPos, length_height, frame_num, frame_dt);
                pause(0.25)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if ~contStim
                    frame_num = max([timeXBack, timeYBack])  * fps;
                    frame_dt = max([timeXBack, timeYBack]) / frame_num;
                    moveBackToStartPosition(hCircle, hArmVertical, hArmHorizontal, theta, startPos, length_cod, circle_radius, frame_num, frame_dt)
                end
             end
            %%%%%%%%%%%%%%%%%%%%%%%%%以下是各个部分的实现函数%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 关于误差函数的计算 使用一个位移函数来实现误差和位移的关系
            % 从起始位置到达物品位置
            function [timeX, timeY, timeZ] = calculateMoveToTargetTimes(initPos, endPos, speedX,speedY)
                % 计算X方向的移动时间
                timeX = abs(endPos(1) - initPos(1)) / speedX;

                % 计算Y方向的移动时间
                timeY = abs(endPos(2) - initPos(2)) / speedY;

                % 计算Z方向的移动时间
                timeZ = abs(endPos(3) - initPos(3)) / speedX;
            end
            function initial_error = calculate_error(initPos, endPos, max_error, base_error)
                total_distance = sqrt((endPos(1) - initPos(1))^2 + (endPos(2) - initPos(2))^2);
                if total_distance == 0
                    initial_error = base_error;
                    return;
                end
                growth_factor = (max_error - base_error) / 100;
                initial_error = base_error + growth_factor * total_distance^2;
                
                initial_error = min(initial_error, max_error);
            end
            function [hCube,hSquare,x, y, z,vertices] = setCubicAndPlate(app,startPos, endPos)
                cubeSize = 2;  % 正方体的边长
                [x, y, z] = meshgrid(linspace(-cubeSize/2, cubeSize/2, 2));  % 创建正方体的顶点数据
                cubeFaces = [1 2 4 3; 2 6 8 4; 3 4 8 7; 1 3 7 5; 5 7 8 6; 1 2 6 5];  % 定义立方体的面
                hCube = patch(app.UIAxes, 'Vertices', [x(:)+endPos(1), y(:)+endPos(2), z(:)+endPos(3)], 'Faces', cubeFaces, 'FaceColor', 'red', 'EdgeColor', 'none');
                squareSize = 4;  % 正方形的边长
                vertices = [squareSize/2, squareSize/2, 0;
                    -squareSize/2, squareSize/2, 0;
                    -squareSize/2, -squareSize/2, 0;
                    squareSize/2, -squareSize/2, 0];
                % 计算实际顶点位置
                vertices = vertices + startPos-[0,0,2];
                % 创建正方形的面
                faces2 = [1 2 3 4];
                % 使用patch函数绘制正方形，指定在app.UIAxes中绘制
                hSquare = patch(app.UIAxes, 'Vertices', vertices, 'Faces', faces2, 'FaceColor', 'green');
                pause(0.5)

            end
            % 移动到错误的区域
            function moveToErrorPosition(app, initPos, endPos, initial_error, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, fps, length_height, length_cod)
                % 计算X和Y方向上到达目的地需要的时间
                [timeXToObjectPos, timeYToObjectPos, timeZToObjectPos] = calculateMoveToTargetTimes(initPos, endPos, speedX,speedY);

                % 计算总帧数和每帧时间间隔
                frame_num = max([timeXToObjectPos, timeYToObjectPos]) * fps;
                frame_dt = max([timeXToObjectPos, timeYToObjectPos]) / frame_num;

                % 初始化计时器
                tic;

                % 在指定帧数内，从初始位置以线性插值的方式移动到错误位置
                for t = linspace(0, 1, frame_num)
                    timer = toc;  % 记录当前循环开始时的时间

                    % 根据已过时间计算当前位置
                    currentTime = toc;
                    fracX = min(currentTime / timeXToObjectPos, 1);
                    fracY = min(currentTime / timeYToObjectPos, 1);

                    % 计算当前帧的新位置，包括预设的误差
                    errorX = initial_error;  % X方向的误差
                    errorY = initial_error;  % Y方向的误差
                    newX = (1-fracX)*initPos(1) + fracX*(endPos(1) + errorX);
                    newY = (1-fracY)*initPos(2) + fracY*(endPos(2) + errorY);

                    % 更新图形显示
                    set(hArmVertical, 'XData', [newX newX], 'YData', [newY newY], 'ZData', [length_height-3, length_height]);
                    set(hArmHorizontal, 'XData', [0, length_cod], 'YData', [newY, newY]);
                    set(hCircle, 'XData', circle_radius * cos(theta) + newX, 'YData', circle_radius * sin(theta) + newY, 'ZData', zeros(size(theta)) + length_height-3);

                    % 计算绘图所用时间并进行帧率控制
                    draw_dt = toc - timer;
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);  % 如果绘制时间小于目标帧时间，暂停以保持帧率
                    else
                        pause(draw_dt - frame_dt);  % 如果绘制时间过长，尝试调整以接近目标帧时间
                    end
                end
            end

            % 使用pid 调整位置
            function[newX,newY] =  adjustPositionWithPID(app, int,endPos, errorPosX, errorPosY, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, hCube,frame_num, frame_dt, length_height, length_cod, Kp, Ki, Kd, prev_errorX, prev_errorY)
                newX = errorPosX;
                newY = errorPosY;
                integralX = 0;
                integralY = 0;
                  trial = 0;
                % 从错误的位置调整到正确的位置
                for t = linspace(0, 1, frame_num)
                    timer = tic;
                   trial = trial+1;

                    % 计算当前误差
                    current_errorX = endPos(1) - newX;
                    current_errorY = endPos(2) - newY;
                    totalError = sqrt(current_errorX^2 + current_errorY^2);
                    updateTable(app, trial,  current_errorX ,  current_errorY, totalError)

                    % 计算积分和微分
                    integralX = integralX + current_errorX * frame_dt;
                    integralY = integralY + current_errorY * frame_dt;
                    derivativeX = (current_errorX - prev_errorX) / frame_dt;
                    derivativeY = (current_errorY - prev_errorY) / frame_dt;

                    % 计算PID输出
                    pid_outputX = Kp * current_errorX + Ki * integralX + Kd * derivativeX;
                    pid_outputY = Kp * current_errorY + Ki * integralY + Kd * derivativeY;

                    % 更新位置
                    newX = newX + pid_outputX;
                    newY = newY+ pid_outputY;


                    %显示新坐标和真实坐标
                    % disp(['当前误差：X方向',num2str(current_errorX),' y方向',num2str(current_errorY)])
                    % disp(" ");
                    % disp(['新坐标的X: ', num2str(newX), '新坐标的Y: ', num2str(newY)]);
                    % disp(" ");
                    % disp(['真实坐标的X: ', num2str(endPos(1)),'真实坐标的Y: ', num2str(endPos(2))]);
                    % disp(" ");
                    %逐步更新显示位置

                    % 终止条件
                    if abs(current_errorX) < 0.13 && abs(current_errorY) < 0.13
                        
                        break;
                    end
                    for t2 = linspace(0, 1, frame_num)
                        timer = toc;
                        newXpid = (1-t2)*(newX - pid_outputX) + t2*(newX);
                        newYpid = (1-t2)*(newY - pid_outputY) + t2*(newY);
                        set(hArmVertical, 'XData', [newXpid newXpid], 'YData', [newYpid newYpid], 'ZData', [length_height-3, length_height]);
                        set(hArmHorizontal, 'XData', [0, length_cod], 'YData', [newYpid newYpid]);
                        set(hCircle, 'XData', circle_radius * cos(theta) + newXpid, 'YData', circle_radius * sin(theta) + newYpid, 'ZData', zeros(size(theta)) + length_height-3);
                        if int == 2
                            set(hCube, 'Vertices', [x(:) + newXpid, y(:) + newYpid, z(:) + length_height-3 - cubeSize / 2]);
                        end
                        draw_dt = toc - timer;
                        if draw_dt < frame_dt
                            pause(frame_dt - draw_dt);
                        else
                            pause(draw_dt-frame_dt);
                        end
                    end
                    % 更新上一次误差
                    prev_errorX = current_errorX;
                    prev_errorY = current_errorY;

                    % 控制绘图帧率
                    draw_dt = toc - timer;
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);
                    else
                        pause(frame_dt);
                    end


                end
            end
            % 不使用pid 
            
            function [newX, newY] = adjustPositionLinearly(app, endPos, startPos, hArmVertical, hArmHorizontal, hCircle, theta, circle_radius, hCube, speedX, speedY, fps, length_height, length_cod)
                % 计算X和Y方向上到达目的地需要的时间
                [timeXToBox, timeYToBox, ~] = calculateMoveToTargetTimes(startPos, endPos, speedX,speedY);

                % 计算总帧数和每帧时间间隔
                frame_num = max( [timeXToBox, timeYToBox]) * fps;
                frame_dt = max([timeXToBox, timeYToBox]) / frame_num;

                newX = startPos(1);
                newY = startPos(2);

                % 从起始位置直线插值到结束位置
                for frame = 1:frame_num
                    timer = tic;

                    % 当前帧时间
                    currentTime = frame * frame_dt;

                    % 独立控制X和Y方向的完成度
                    fracX = min(currentTime / timeXToBox, 1);
                    fracY = min(currentTime / timeYToBox, 1);

                    % 线性插值计算新位置
                    newX = (1 - fracX) * startPos(1) + fracX * endPos(1);
                    newY = (1 - fracY) * startPos(2) + fracY * endPos(2);

                    % 更新显示位置
                    set(hArmVertical, 'XData', [newX newX], 'YData', [newY newY], 'ZData', [length_height-3, length_height]);
                    set(hArmHorizontal, 'XData', [0, length_cod], 'YData', [newY newY]);
                    set(hCircle, 'XData', circle_radius * cos(theta) + newX, 'YData', circle_radius * sin(theta) + newY, 'ZData', zeros(size(theta)) + length_height-3);
                    if exist('hCube', 'var')
                        set(hCube, 'Vertices', [x(:) + newX, y(:) + newY, z(:) + length_height-3 - cubeSize / 2]);
                    end

                    draw_dt = toc(timer);
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);
                    else
                        pause(draw_dt - frame_dt);
                    end

                end
            end

            % Z方向上的移动
            function executeZAxisMovement(app, endPos, length_height, hArmVertical, hCircle, hCube, fps, timeZToObjectPos, circle_radius, cubeSize, newX, newY, theta)
                % 初始化Z方向下降接触立方体的动作
                frame_num = timeZToObjectPos * fps;
                frame_dt = timeZToObjectPos / frame_num;
                disp(frame_dt)
                for t = linspace(0, 1, frame_num)
                    timer = tic;
                    newZ = (1-t)*(length_height-3) + t*(endPos(3) + cubeSize / 2 + circle_radius);  % 确定每个步骤的Z坐标
                    drawnow;
                    set(hArmVertical, 'ZData', [newZ, length_height]);  % 更新纵杆的Z数据
                    set(hCircle, 'ZData', zeros(size(theta)) + newZ);  % 更新圆形抓取器的Z数据
                    draw_dt = toc - timer;
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);
                        
                    else
                        pause(frame_dt);
                    end
                end

                pause(0.25);  % 短暂暂停，模拟抓取操作

                % 正方体和圆盘一起上升回到初始位置
                for t = linspace(0, 1, frame_num)
                    if(t < 3 / frame_num)
                        pause(0.15);  % 在开始阶段稍作暂停
                    end
                    timer = tic;
                    newZ = (1-t)*(endPos(3) + cubeSize / 2 + circle_radius) + t*(length_height-3);
                    drawnow;
                    set(hCircle, 'ZData', zeros(size(theta)) + newZ);
                    set(hCube, 'Vertices', [x(:) + newX, y(:) + newY, z(:) + newZ - cubeSize / 2]);  % 更新正方体的位置
                    set(hArmVertical,'ZData', [newZ, length_height]);  % 更新纵杆的Z数据
                    draw_dt = toc - timer;
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);
                    else
                        pause(frame_dt);
                    end
                end
            end


            % 下降并分离
            function lowerAndSeparate(hCircle, hArmVertical, hCube, theta, startPos, length_height, cubeSize, frame_num, frame_dt)
                for t = linspace(0, 1, frame_num)
                    timer = tic;
                    newZ = (1-t) * (length_height-3) + t * startPos(3);  % 计算下降后的新Z位置
                    drawnow;
                    set(hCircle, 'ZData', zeros(size(theta)) + newZ);
                    set(hArmVertical, 'ZData', [newZ, length_height]);
                    set(hCube, 'Vertices', [x(:) + startPos(1), y(:) + startPos(2), z(:) + newZ - cubeSize / 2]);  % 更新正方体的位置
                    draw_dt = toc - timer;
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);
                    else
                        pause(frame_dt);
                    end
                end
            end
            % 仅圆盘上升
            function riseOnlyPlate(hCircle, hArmVertical, theta, startPos, length_height, frame_num, frame_dt)
                for t = linspace(0, 1, frame_num)
                    timer = tic;
                    newZ = (1-t) * startPos(3) + t * (length_height-3);  % 计算上升后的新Z位置
                    drawnow;
                    set(hCircle, 'ZData', zeros(size(theta)) + newZ);
                    set(hArmVertical, 'ZData', [newZ, length_height]);
                    draw_dt = toc - timer;
                    if draw_dt < frame_dt
                        pause(frame_dt - draw_dt);
                    else
                        pause(frame_dt);
                    end
                end
            end
            % 回到原始位置。
                function moveBackToStartPosition(hCircle, hArmVertical, hArmHorizontal, theta, startPos, length_cod, circle_radius, frame_num, frame_dt)
                    % 初始化X和Y方向上到达(0,0)的目标时间
                     [timeXBack, timeYBack, timeZBack] = calculateMoveToTargetTimes(startPos, initPos, speedX,speedY);
                    % 计算总帧数和每帧时间间隔
                    frame_num = max([timeXBack, timeYBack]) * frame_num;
                    frame_dt = max([timeXBack, timeYBack]) / frame_num;

                    % 从起始位置逐步移动回起始点(0,0)
                    for frame = 1:frame_num
                        timer = tic;  % 开始计时器

                        % 当前帧时间
                        currentTime = frame * frame_dt;

                        % 独立控制X和Y方向的完成度
                        fracX = min(currentTime / timeXBack, 1);
                        fracY = min(currentTime / timeYBack, 1);

                        % 线性插值计算新的X和Y坐标
                        newX = (1 - fracX) * startPos(1);
                        newY = (1 - fracY) * startPos(2);

                        % 更新圆盘和机械臂的位置
                        set(hCircle, 'XData', circle_radius * cos(theta) + newX, 'YData', circle_radius * sin(theta) + newY);
                        set(hArmVertical, 'XData', [newX newX], 'YData', [newY newY]);
                        set(hArmHorizontal, 'XData', [0 length_cod], 'YData', [newY newY]);

                        draw_dt = toc(timer);
                        if draw_dt < frame_dt
                            pause(frame_dt - draw_dt);  % 控制帧率，以保持动画平滑
                        else
                            pause(frame_dt);
                        end
                    end
                end


            function updateTable(app, trial, xError, yError, totalError)
                % 此函数为表格添加一行新数据，包含当前试验的误差信息

                % 创建新数据条目作为一行
                newRow = {int64(trial), xError, yError, totalError};

                % 将新行添加到现有数据中
                currentData = get(app.UITable, 'Data');
                newData = [currentData; newRow];
                set(app.UITable, 'Data', newData);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {407, 407};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {244, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '电机A转速(rpm)';
            app.speed = uieditfield(app.LeftPanel, 'numeric');
            app.speed.Value = 40;
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '退出';
            app.XLabel_2 = uilabel(app.LeftPanel);
            app.XLabel_2.Text = '目的地位置';
            app.Xstart = uieditfield(app.LeftPanel, 'numeric');
            app.Xstart.Value = 18;
            app.Ystart = uieditfield(app.LeftPanel, 'numeric');
            app.Ystart.Value = 10;
            app.Zstart = uieditfield(app.LeftPanel, 'numeric');
            app.Zstart.Value = 3;
            app.XLabel = uilabel(app.LeftPanel);
            app.XLabel.Text = '药品位置';
            app.Xend = uieditfield(app.LeftPanel, 'numeric');
            app.Xend.Value = 10;
            app.Yend = uieditfield(app.LeftPanel, 'numeric');
            app.Yend.Value = 5;
            app.Zend = uieditfield(app.LeftPanel, 'numeric');
            app.Zend.Value = 3;
            app.XButton = uibutton(app.LeftPanel, 'state');
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.LeftPanel, 'state');
            app.YButton.Text = 'Y';
            app.ZButton = uibutton(app.LeftPanel, 'state');
            app.ZButton.Text = 'Z';
            app.Lamp = uilamp(app.LeftPanel);
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.Text = '生成药物';
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.CheckBox.Text = '自动生成药物';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '电机B转速(rpm)';
            app.speed_2 = uieditfield(app.LeftPanel, 'numeric');
            app.speed_2.Value = 40;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'trials'; 'Xerror'; 'Yerror'; 'ERROR'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPIDnew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end